classdef SmartKitchenSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ItemsTab                        matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        QTYEditField                    matlab.ui.control.NumericEditField
        QTYEditFieldLabel               matlab.ui.control.Label
        UPCEditField                    matlab.ui.control.NumericEditField
        UPCEditFieldLabel               matlab.ui.control.Label
        AddItemButton                   matlab.ui.control.Button
        UpdateItemButton                matlab.ui.control.Button
        IngredientNameEditField_2       matlab.ui.control.EditField
        IngredientNameEditField_2Label  matlab.ui.control.Label
        RemoveItemButton                matlab.ui.control.Button
        RecipesTab                      matlab.ui.container.Tab
        DeleteSelectedRecipeButton      matlab.ui.control.Button
        RecipeDropdownDropDown          matlab.ui.control.DropDown
        RecipeDropdownDropDownLabel     matlab.ui.control.Label
        QuantityEditField               matlab.ui.control.NumericEditField
        QuantityEditFieldLabel          matlab.ui.control.Label
        UPCEditField_2                  matlab.ui.control.NumericEditField
        UPCEditField_2Label             matlab.ui.control.Label
        AddIngredientButton             matlab.ui.control.Button
        UseRecipeButton                 matlab.ui.control.Button
        AddRecipeButton                 matlab.ui.control.Button
        NewRecipeNameEditField          matlab.ui.control.EditField
        NewRecipeNameEditFieldLabel     matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
    end

properties (Access = private)
    % Private properties for internal app use
    Property % Placeholder for any additional private property
end

methods (Access = private)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
function addItemToStock(app, filename, upc, ingredient, qty)
    % Ensure the inventory file exists
    if exist(filename, 'file') ~= 2
        % Create the file with headers if it doesn't exist
        fileID = fopen(filename, 'w');
        fprintf(fileID, 'UPC,Ingredient,Quantity\n'); % Add headers
        fclose(fileID);
    end

    % Read the existing inventory
    inventoryTable = app.getInventoryList(filename);

    % Check if the UPC already exists
    idx = find(inventoryTable.UPC == string(upc), 1);
    if ~isempty(idx)
        % If UPC exists, update the quantity
        inventoryTable.Quantity(idx) = inventoryTable.Quantity(idx) + qty;
    else
        % If UPC does not exist, append the new item
        newRow = table(string(upc), {ingredient}, qty, 'VariableNames', {'UPC', 'Ingredient', 'Quantity'});
        inventoryTable = [inventoryTable; newRow];
    end

    % Save the updated inventory back to the file
    writetable(inventoryTable, filename);

    % Refresh the inventory table in the UI
    app.UITable.Data = inventoryTable;
end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
function inventoryTable = getInventoryList(app, filename)
    % Ensure the file exists, creating it with headers if necessary
    if ~exist(filename, 'file')
        fileID = fopen(filename, 'w');
        fprintf(fileID, 'UPC,Ingredient,Quantity\n'); % Add headers
        fclose(fileID);
    end

    % Read the file into a table
    inventoryTable = readtable(filename);

    % Convert the UPC column to string to avoid scientific notation
    inventoryTable.UPC = string(inventoryTable.UPC);

    % Handle duplicates by combining quantities
    [uniqueUPCs, ~, idx] = unique(inventoryTable.UPC); % Find unique UPCs
    combinedQuantities = accumarray(idx, inventoryTable.Quantity); % Sum quantities for each unique UPC

    % Create a new table with combined quantities
    combinedIngredients = inventoryTable.Ingredient(idx); % Get corresponding ingredients
    inventoryTable = table(uniqueUPCs, combinedIngredients, combinedQuantities, ...
        'VariableNames', {'UPC', 'Ingredient', 'Quantity'});

    % Sort the table for better readability (optional)
    inventoryTable = sortrows(inventoryTable, 'UPC');
end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    % Function to initialize the inventory and populate the UITable
    function initializeInventory(app)
        inventoryFile = 'inventory.csv'; % File name for inventory

        % Ensure the inventory file exists
        if ~exist(inventoryFile, 'file')
            % Create the file with headers if it doesn't exist
            fileID = fopen(inventoryFile, 'w');
            fprintf(fileID, 'UPC,Ingredient,Quantity\n'); % Add headers
            fclose(fileID);
        end

        % Load inventory data into the UITable
        app.UITable.Data = app.getInventoryList(inventoryFile);
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
function populateRecipeDropdown(app)
    disp('populateRecipeDropdown called'); % Debug

    if ~exist('recipes.csv', 'file')
        disp('recipes.csv file does not exist.'); % Debug
        app.RecipeDropdownDropDown.Items = {'No Recipe Selected'};
        app.RecipeDropdownDropDown.Value = 'No Recipe Selected';
        return;
    end

    % Read recipes.csv
    try
        fileID = fopen('recipes.csv', 'r');
        recipes = textscan(fileID, '%s', 'Delimiter', '\n');
        fclose(fileID);

        % Filter recipe names
        recipes = recipes{1};
        recipeNames = recipes(~contains(recipes, ',') & ~strcmp(recipes, '---'));

        % Debugging: Display recipe names
        disp('Recipes found in CSV:');
        disp(recipeNames);

        % Update dropdown items
        if isempty(recipeNames)
            app.RecipeDropdownDropDown.Items = {'No Recipe Selected'};
        else
            app.RecipeDropdownDropDown.Items = [{'No Recipe Selected'}; recipeNames];
        end

        % Set default value
        app.RecipeDropdownDropDown.Value = 'No Recipe Selected';
        disp('Dropdown updated'); % Debug
    catch ME
        disp(['Error reading recipes.csv: ', ME.message]); % Debug
        uialert(app.UIFigure, 'Error reading recipe file.', 'Error');
    end
end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
function RecipeDropdownChanged(app, ~)
    % Get the selected recipe name
    selectedRecipe = app.RecipeDropdownDropDown.Value;

    % Handle the default option
    if strcmp(selectedRecipe, 'No Recipe Selected')
        app.UITable_2.Data = table([], [], 'VariableNames', {'Ingredient', 'Quantity'});
        return;
    end

    % Load the recipe file
    if ~exist('recipes.csv', 'file')
        uialert(app.UIFigure, 'No recipes file found.', 'Error');
        return;
    end

    % Read all lines from the recipe file
    fileID = fopen('recipes.csv', 'r');
    recipes = textscan(fileID, '%s', 'Delimiter', '\n');
    fclose(fileID);

    recipes = recipes{1}; % Extract the lines

    % Locate the selected recipe
    recipeStart = find(strcmp(recipes, selectedRecipe));

    % Check if recipe exists
    if isempty(recipeStart)
        uialert(app.UIFigure, 'Recipe not found.', 'Error');
        return;
    end

    % Extract ingredient details for the selected recipe
    ingredients = {};
    quantities = [];
    i = recipeStart + 1;
    while i <= length(recipes) && ~strcmp(recipes{i}, '---') % Stop at the separator
        parts = split(recipes{i}, ',');
        ingredients{end+1} = parts{1}; %#ok<AGROW>
        quantities(end+1) = str2double(parts{2}); %#ok<AGROW>
        i = i + 1;
    end

    % Update the UITable_2 with the recipe ingredients
    app.UITable_2.Data = table(ingredients', quantities', 'VariableNames', {'Ingredient', 'Quantity'});
end


end %the end that goes with (methods)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)

    % Initialize inventory and dropdown
    initializeInventory(app);
    populateRecipeDropdown(app);

    % Dynamically set the dropdown ValueChangedFcn
    app.RecipeDropdownDropDown.ValueChangedFcn = @(src, event) RecipeDropdownChanged(app, event);


        end

        % Button pushed function: AddItemButton
        function AddItemButtonPushed(app, event)

    disp('AddItemButtonPushed called');

    % Retrieve input values
    upc = app.UPCEditField.Value;
    ingredient = app.IngredientNameEditField_2.Value;
    qty = app.QTYEditField.Value;

    % Debugging: Check input values
    disp(['UPC: ', num2str(upc)]);
    disp(['Ingredient: ', ingredient]);
    disp(['Quantity: ', num2str(qty)]);

    % Ensure UPC is treated as a string
    if isnumeric(upc)
        upc = num2str(upc); % Convert numeric UPC to string
    end
    upc = string(upc); % Ensure UPC is a string

    % Validate input
    if isempty(upc) || isempty(ingredient) || qty < 0
        uialert(app.UIFigure, 'Invalid input. Ensure all fields are filled and quantity is non-negative.', 'Error');
        return;
    end

    % Call addItemToStock to add the item
    app.addItemToStock('inventory.csv', upc, ingredient, qty);

    % Refresh the inventory table
    app.UITable.Data = app.getInventoryList('inventory.csv');
    disp('Table updated after adding item');

        end

        % Button pushed function: UpdateItemButton
        function UpdateItemButtonPushed(app, event)

    % Get values from Edit Fields
    upc = app.UPCEditField.Value;
    qty = app.QTYEditField.Value;

    % Ensure UPC is treated as a string
    if isnumeric(upc)
        upc = num2str(upc);
    end
    upc = string(upc);

    % Validate inputs
    if isempty(upc) || qty < 0
        uialert(app.UIFigure, 'Invalid input. Ensure UPC is filled and quantity is non-negative.', 'Error');
        return;
    end

    % Update the inventory
    inventoryTable = app.getInventoryList('inventory.csv');
    idx = strcmp(inventoryTable.UPC, upc);
    if any(idx)
        inventoryTable.Quantity(idx) = qty;
        writetable(inventoryTable, 'inventory.csv'); % Save updated inventory
    else
        uialert(app.UIFigure, 'UPC not found in inventory.', 'Error');
    end

    % Refresh the inventory table
    app.UITable.Data = inventoryTable;
        end

        % Button pushed function: RemoveItemButton
        function RemoveItemButtonPushed(app, event)
    % Get UPC from Edit Field
    upc = app.UPCEditField.Value;

    % Ensure UPC is treated as a string
    if isnumeric(upc)
        upc = num2str(upc);
    end
    upc = string(upc);

    % Validate input
    if isempty(upc)
        uialert(app.UIFigure, 'Invalid input. Enter a valid UPC.', 'Error');
        return;
    end

    % Remove the item from the inventory
    inventoryTable = app.getInventoryList('inventory.csv');
    idx = strcmp(inventoryTable.UPC, upc);
    if any(idx)
        inventoryTable(idx, :) = [];
        writetable(inventoryTable, 'inventory.csv'); % Save updated inventory
    else
        uialert(app.UIFigure, 'UPC not found in inventory.', 'Error');
    end

    % Refresh the inventory table
    app.UITable.Data = inventoryTable;
        
        end

        % Button pushed function: AddRecipeButton
        function AddRecipeButtonPushed(app, event)

    % Get the new recipe name
    newRecipeName = app.NewRecipeNameEditField.Value;

    % Validate the recipe name
    if isempty(newRecipeName)
        uialert(app.UIFigure, 'Please enter a new recipe name.', 'Error');
        return;
    end

    % Get the data from the recipe table
    ingredientsTable = app.UITable_2.Data;

    % Validate the recipe table data
    if isempty(ingredientsTable) || any(cellfun(@isempty, ingredientsTable.Ingredient)) || any(ingredientsTable.Quantity <= 0)
        uialert(app.UIFigure, 'Please add valid ingredients and quantities to save the recipe.', 'Error');
        return;
    end

    % Write to the recipes.csv file
    try
        if ~exist('recipes.csv', 'file')
            fileID = fopen('recipes.csv', 'w');
        else
            fileID = fopen('recipes.csv', 'a');
        end

        % Write the recipe name and ingredients
        fprintf(fileID, '%s\n', newRecipeName);
        for i = 1:height(ingredientsTable)
            fprintf(fileID, '%s,%f\n', ingredientsTable.Ingredient{i}, ingredientsTable.Quantity(i));
        end
        fprintf(fileID, '---\n');
        fclose(fileID);

        % Notify the user
        uialert(app.UIFigure, 'Recipe added successfully!', 'Success');

        % Clear the recipe table and reset the recipe name field
        app.UITable_2.Data = table([], [], 'VariableNames', {'Ingredient', 'Quantity'});
        app.NewRecipeNameEditField.Value = '';
        populateRecipeDropdown(app); % Update dropdown
    catch
        uialert(app.UIFigure, 'An error occurred while saving the recipe.', 'Error');
    end


        end

        % Callback function
        function ViewRecipeDetailsButtonPushed(app, event)
% Button pushed function: ViewRecipeDetailsButton
function ViewRecipeDetailsButtonPushed(app, ~)
    % Get the selected recipe name from the dropdown
    selectedRecipe = app.RecipeDropdownDropDown.Value;

    % Ensure a valid recipe is selected
    if strcmp(selectedRecipe, 'No Recipes Available')
        app.UITable_2.Data = table([], [], 'VariableNames', {'Ingredient', 'Quantity'});
        uialert(app.UIFigure, 'No recipe selected.', 'Error');
        return;
    end

    % Load the recipe file
    if ~exist('recipes.csv', 'file')
        uialert(app.UIFigure, 'No recipes file found.', 'Error');
        return;
    end

    % Read all lines from the recipe file
    fileID = fopen('recipes.csv', 'r');
    recipes = textscan(fileID, '%s', 'Delimiter', '\n');
    fclose(fileID);

    recipes = recipes{1}; % Extract the lines

    % Locate the selected recipe
    recipeStart = find(strcmp(recipes, selectedRecipe));

    % Check if recipe exists
    if isempty(recipeStart)
        uialert(app.UIFigure, 'Recipe not found.', 'Error');
        return;
    end

    % Extract ingredient details for the selected recipe
    ingredients = {};
    quantities = [];
    i = recipeStart + 1;
    while i <= length(recipes) && ~strcmp(recipes{i}, '---') % Stop at the separator
        parts = split(recipes{i}, ',');
        ingredients{end+1} = parts{1}; %#ok<AGROW>
        quantities(end+1) = str2double(parts{2}); %#ok<AGROW>
        i = i + 1;
    end

    % Update the UITable_2 with the recipe ingredients
    app.UITable_2.Data = table(ingredients', quantities', 'VariableNames', {'Ingredient', 'Quantity'});
end

        end

        % Button pushed function: UseRecipeButton
        function UseRecipeButtonPushed(app, event)

    % Get the selected recipe name from the dropdown
    selectedRecipe = app.RecipeDropdownDropDown.Value;

    % Ensure a valid recipe is selected
    if strcmp(selectedRecipe, 'No Recipe Selected')
        uialert(app.UIFigure, 'Please select a recipe to use.', 'Error');
        return;
    end

    % Check if the recipes file exists
    if ~exist('recipes.csv', 'file')
        uialert(app.UIFigure, 'No recipes file found.', 'Error');
        return;
    end

    % Read all lines from the recipes file
    fileID = fopen('recipes.csv', 'r');
    recipes = textscan(fileID, '%s', 'Delimiter', '\n');
    fclose(fileID);
    recipes = recipes{1}; % Extract lines

    % Locate the selected recipe
    recipeStart = find(strcmp(recipes, selectedRecipe));
    if isempty(recipeStart)
        uialert(app.UIFigure, 'Recipe not found in the file.', 'Error');
        return;
    end

    % Extract ingredient details
    ingredients = {};
    quantities = [];
    i = recipeStart + 1;
    while i <= length(recipes) && ~strcmp(recipes{i}, '---')
        parts = split(recipes{i}, ',');
        ingredients{end+1} = parts{1}; %#ok<AGROW>
        quantities(end+1) = str2double(parts{2}); %#ok<AGROW>
        i = i + 1;
    end

    % Load the inventory data
    inventoryTable = app.getInventoryList('inventory.csv');

    % Check if all ingredients are available in sufficient quantity
    for j = 1:length(ingredients)
        idx = strcmp(inventoryTable.Ingredient, ingredients{j});
        if any(idx)
            if inventoryTable.Quantity(idx) < quantities(j)
                uialert(app.UIFigure, ['Insufficient quantity for ingredient: ', ingredients{j}], 'Error');
                return;
            end
        else
            uialert(app.UIFigure, ['Ingredient not found in inventory: ', ingredients{j}], 'Error');
            return;
        end
    end

    % Deduct the quantities from inventory
    for j = 1:length(ingredients)
        idx = strcmp(inventoryTable.Ingredient, ingredients{j});
        inventoryTable.Quantity(idx) = inventoryTable.Quantity(idx) - quantities(j);
    end

    % Save the updated inventory back to the file
    writetable(inventoryTable, 'inventory.csv');

    % Refresh the inventory table in the app
    app.UITable.Data = inventoryTable;

    % Notify the user of success
    uialert(app.UIFigure, ['Recipe "', selectedRecipe, '" used successfully!'], 'Success');


        end

        % Button pushed function: AddIngredientButton
        function AddIngredientButtonPushed(app, event)

    % Get UPC and Quantity
    upc = app.UPCEditField_2.Value; % Use UPCEditField_2 for the Recipes Tab
    qty = app.QuantityEditField.Value; % Use QuantityEditField for quantity

    % Ensure UPC is treated as a string
    if isnumeric(upc)
        upc = num2str(upc);
    end
    upc = string(upc);

    % Validate UPC and Quantity
    if isempty(upc) || qty <= 0
        uialert(app.UIFigure, 'Invalid input. Ensure UPC is filled and quantity is positive.', 'Error');
        return;
    end

    % Load inventory and find the ingredient
    inventoryTable = app.getInventoryList('inventory.csv');
    idx = strcmp(inventoryTable.UPC, upc);

    if any(idx)
        % Fetch the ingredient name
        ingredient = inventoryTable.Ingredient{idx};

        % Add the ingredient to the recipe table
        currentData = app.UITable_2.Data;

        % If the recipe table is empty, initialize it
        if isempty(currentData)
            newRow = table({ingredient}, qty, 'VariableNames', {'Ingredient', 'Quantity'});
            app.UITable_2.Data = newRow;
        else
            % Append the ingredient to the recipe table
            newRow = table({ingredient}, qty, 'VariableNames', {'Ingredient', 'Quantity'});
            app.UITable_2.Data = [currentData; newRow];
        end
    else
        % Show error if UPC is not found in the inventory
        uialert(app.UIFigure, 'UPC not found in the inventory.', 'Error');
    end


        end

        % Button pushed function: DeleteSelectedRecipeButton
        function DeleteSelectedRecipeButtonPushed(app, event)

    % Get the selected recipe name from the dropdown
    selectedRecipe = app.RecipeDropdownDropDown.Value;

    % Check if a valid recipe is selected
    if strcmp(selectedRecipe, 'No Recipe Selected') || isempty(selectedRecipe)
        uialert(app.UIFigure, 'No recipe selected to delete.', 'Error');
        return;
    end

    % Ensure the recipes file exists
    if ~exist('recipes.csv', 'file')
        uialert(app.UIFigure, 'No recipes file found.', 'Error');
        return;
    end

    % Read all lines from the recipes.csv file
    fileID = fopen('recipes.csv', 'r');
    allLines = textscan(fileID, '%s', 'Delimiter', '\n');
    fclose(fileID);
    allLines = allLines{1};

    % Locate the start and end of the selected recipe
    recipeStart = find(strcmp(allLines, selectedRecipe), 1);
    if isempty(recipeStart)
        uialert(app.UIFigure, 'Recipe not found in the file.', 'Error');
        return;
    end

    % Find the end of the recipe (marked by "---" or end of file)
    recipeEnd = recipeStart;
    while recipeEnd <= length(allLines) && ~strcmp(allLines{recipeEnd}, '---')
        recipeEnd = recipeEnd + 1;
    end

    % Remove the lines corresponding to the recipe
    allLines(recipeStart:recipeEnd) = [];

    % Write the updated lines back to the recipes.csv file
    fileID = fopen('recipes.csv', 'w');
    fprintf(fileID, '%s\n', allLines{:});
    fclose(fileID);

    % Notify the user of successful deletion
    uialert(app.UIFigure, 'Recipe deleted successfully!', 'Success');

    % Refresh the dropdown
    populateRecipeDropdown(app);

    % Reset the UITable_2 to the unselected state
    app.UITable_2.Data = table([], [], 'VariableNames', {'Ingredient', 'Quantity'});


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ItemsTab = uitab(app.TabGroup);
            app.ItemsTab.Title = 'Items';
            app.RemoveItemButton = uibutton(app.ItemsTab, 'push');
            app.RemoveItemButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveItemButtonPushed, true);
            app.RemoveItemButton.Text = 'Remove Item';
            app.IngredientNameEditField_2Label = uilabel(app.ItemsTab);
            app.IngredientNameEditField_2Label.Text = 'Ingredient Name';
            app.IngredientNameEditField_2 = uieditfield(app.ItemsTab, 'text');
            app.UpdateItemButton = uibutton(app.ItemsTab, 'push');
            app.UpdateItemButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateItemButtonPushed, true);
            app.UpdateItemButton.Text = 'Update Item';
            app.AddItemButton = uibutton(app.ItemsTab, 'push');
            app.AddItemButton.ButtonPushedFcn = createCallbackFcn(app, @AddItemButtonPushed, true);
            app.AddItemButton.Text = 'Add Item';
            app.UPCEditFieldLabel = uilabel(app.ItemsTab);
            app.UPCEditFieldLabel.Text = 'UPC';
            app.UPCEditField = uieditfield(app.ItemsTab, 'numeric');
            app.QTYEditFieldLabel = uilabel(app.ItemsTab);
            app.QTYEditFieldLabel.Text = 'QTY';
            app.QTYEditField = uieditfield(app.ItemsTab, 'numeric');
            app.UITable = uitable(app.ItemsTab);
            app.UITable.ColumnName = {'UPC'; 'Ingredient'; 'Quantity'};
            app.UITable.RowName = {};
            app.RecipesTab = uitab(app.TabGroup);
            app.RecipesTab.Title = 'Recipes';
            app.RecipesTab.Tag = 'Recipe';
            app.UITable_2 = uitable(app.RecipesTab);
            app.UITable_2.ColumnName = {'Ingredient'; 'Quantity'};
            app.UITable_2.RowName = {};
            app.NewRecipeNameEditFieldLabel = uilabel(app.RecipesTab);
            app.NewRecipeNameEditFieldLabel.Text = 'New Recipe Name';
            app.NewRecipeNameEditField = uieditfield(app.RecipesTab, 'text');
            app.AddRecipeButton = uibutton(app.RecipesTab, 'push');
            app.AddRecipeButton.ButtonPushedFcn = createCallbackFcn(app, @AddRecipeButtonPushed, true);
            app.AddRecipeButton.Text = 'Add Recipe';
            app.UseRecipeButton = uibutton(app.RecipesTab, 'push');
            app.UseRecipeButton.ButtonPushedFcn = createCallbackFcn(app, @UseRecipeButtonPushed, true);
            app.UseRecipeButton.Text = 'Use Recipe';
            app.AddIngredientButton = uibutton(app.RecipesTab, 'push');
            app.AddIngredientButton.ButtonPushedFcn = createCallbackFcn(app, @AddIngredientButtonPushed, true);
            app.AddIngredientButton.Text = 'Add Ingredient';
            app.UPCEditField_2Label = uilabel(app.RecipesTab);
            app.UPCEditField_2Label.Text = 'UPC';
            app.UPCEditField_2 = uieditfield(app.RecipesTab, 'numeric');
            app.QuantityEditFieldLabel = uilabel(app.RecipesTab);
            app.QuantityEditFieldLabel.Text = 'Quantity';
            app.QuantityEditField = uieditfield(app.RecipesTab, 'numeric');
            app.RecipeDropdownDropDownLabel = uilabel(app.RecipesTab);
            app.RecipeDropdownDropDownLabel.Text = 'RecipeDropdown';
            app.RecipeDropdownDropDown = uidropdown(app.RecipesTab);
            app.RecipeDropdownDropDown.Tag = 'Recipe';
            app.DeleteSelectedRecipeButton = uibutton(app.RecipesTab, 'push');
            app.DeleteSelectedRecipeButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedRecipeButtonPushed, true);
            app.DeleteSelectedRecipeButton.Text = 'Delete Selected Recipe';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartKitchenSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end